classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        x         matlab.ui.control.NumericEditField
        y         matlab.ui.control.NumericEditField
        z         matlab.ui.control.NumericEditField
        pb1       matlab.ui.control.Button
        pb2       matlab.ui.control.Button
        pb3       matlab.ui.control.Button
    end


    properties (Access = private)
        mav % Description
    end


    methods (Access = private)

        % Button pushed function: pb1
        function pb1ButtonPushed(app, event)
            number_of_targets=1;
            app.mav=MatMav(number_of_targets);
            
             
            app.mav.set_COMPORT('COM5'); % on Windows
            app.mav.set_BAUDRATE(57600);
            % connect
            app.mav.ConnectSerial()
            
        end

        % Button pushed function: pb2
        function pb2ButtonPushed(app, event)
            app.x.Value = app.mav.x
            app.y.Value = app.mav.y
            app.z.value = app.mav.z
        end

        % Button pushed function: pb3
        function pb3ButtonPushed(app, event)
            app.mav.Disconnect();
            app,mav.delete();
            clear;
            

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.x = uieditfield(app.UIFigure, 'numeric');
            app.y = uieditfield(app.UIFigure, 'numeric');
            app.z = uieditfield(app.UIFigure, 'numeric');
            app.pb1 = uibutton(app.UIFigure, 'push');
            app.pb1.ButtonPushedFcn = createCallbackFcn(app, @pb1ButtonPushed, true);
            app.pb1.Text = 'Creat MAV';
            app.pb2 = uibutton(app.UIFigure, 'push');
            app.pb2.ButtonPushedFcn = createCallbackFcn(app, @pb2ButtonPushed, true);
            app.pb2.Text = 'Get NED';
            app.pb3 = uibutton(app.UIFigure, 'push');
            app.pb3.ButtonPushedFcn = createCallbackFcn(app, @pb3ButtonPushed, true);
            app.pb3.Text = 'Kill MAV';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end